classdef project_BME_404 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DevelopedByTasniaBinteMamun1818027Label  matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
        RegionGrowingApproachButton  matlab.ui.control.Button
        KmeansClusteringButton       matlab.ui.control.Button
        ManualThresholdButton        matlab.ui.control.Button
        SegmentationoftumorLabel     matlab.ui.control.Label
        MedianFilterButton           matlab.ui.control.Button
        FilterforRemovingNoiseLabel  matlab.ui.control.Label
        CannyButton                  matlab.ui.control.Button
        PrewittButton                matlab.ui.control.Button
        SobelButton                  matlab.ui.control.Button
        EdgeDetectionTechniqueLabel  matlab.ui.control.Label
        HighPassFilteringButton      matlab.ui.control.Button
        ContrastInhancementButton    matlab.ui.control.Button
        CoverttoGrayscaleButton      matlab.ui.control.Button
        LoadaBrainMRIimageButton     matlab.ui.control.Button
        BrainTumorDetectionBasedonSegmentationLabel  matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        inp % Description
        s
        inp_con_enhance
        tumor
        inp1
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadaBrainMRIimageButton
        function LoadaBrainMRIimageButtonPushed(app, event)
            %[I,path]=uigetfile('*.jpg','select a input image');
            %str=strcat(path,I);
            app.s=imread('4Perfect.jpg');
            imshow(app.s,'Parent',app.UIAxes);
            
          
        end

        % Button pushed function: CoverttoGrayscaleButton
        function CoverttoGrayscaleButtonPushed(app, event)
            app.inp=imresize(app.s,[256,256]);
            if size(app.inp,3)>1
                app.inp=rgb2gray(app.inp);
            end
            imshow(app.inp,'Parent',app.UIAxes_2);
        end

        % Button pushed function: ContrastInhancementButton
        function ContrastInhancementButtonPushed(app, event)
            app.inp_con_enhance= histeq(app.inp);
            imshow(app.inp_con_enhance,'Parent',app.UIAxes_2);
        end

        % Button pushed function: HighPassFilteringButton
        function HighPassFilteringButtonPushed(app, event)
            p= padding(size(app.inp_con_enhance));
            F= fft2(app.inp_con_enhance,p(1),p(2));

            D0=0.005*p(1);
            w= lp_filter('butterworth',p(1),p(2),D0,20);
            hp_w= 1-w;

            filtered= hp_w.*F;
            filtered_img= real(ifft2(filtered));
            filtered_img= filtered_img(1:size(app.inp,1),1:size(app.inp,2));

            imshow(filtered_img,[],'Parent',app.UIAxes_2); 
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            filtered_sobel= edge_detection(app.inp,"sobel");

            imshow(filtered_sobel,[],'Parent',app.UIAxes_2);
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            filtered_prewitt= edge_detection(app.inp,"prewitt");

            imshow(filtered_prewitt,[],'Parent',app.UIAxes_2);
        end

        % Button pushed function: CannyButton
        function CannyButtonPushed(app, event)
            filtered_canny= edge_detection(app.inp,"canny");

            imshow(filtered_canny,[],'Parent',app.UIAxes_2);

        end

        % Button pushed function: MedianFilterButton
        function MedianFilterButtonPushed(app, event)
            N= imnoise(app.inp,'salt & pepper',0.01); %Adding noise

            filtered_noise= medfilt2(N,[3 3],'symmetric');  %filtered using symmetric padding
            
            imshow(N,[],'Parent',app.UIAxes)
          
            imshow(filtered_noise,[],'Parent',app.UIAxes_2);
        end

        % Button pushed function: ManualThresholdButton
        function ManualThresholdButtonPushed(app, event)
            %thresh= thresh_tool(uint16(inp_con_enhance),'gray');
            bw= app.inp_con_enhance>240;
            bw= imfill(bw,'holes');
            nhood= strel('disk',3);
            bw1= imopen(bw, nhood);
     
            label=bwlabel(bw1);
            stats=regionprops(label,'Solidity','Area','BoundingBox');
            density=[stats.Solidity];
            area=[stats.Area];
            high_dense_area=density>0.85;
            max_area=max(area(high_dense_area));
            tumor_label=find(area==max_area);
            app.tumor=ismember(label,tumor_label);
            
            app.inp1 = app.inp;
            app.inp1(app.tumor) = 255;
              
            if max_area>1000
                              
            else
                h = msgbox('No Tumor!!','status');
                %disp('no tumor');
                return;
            end

            % Getting Tumor Outline - image filling, eroding, subtracting
            % erosion the walls by a few pixels

            dilationAmount = 3;
            rad = floor(dilationAmount);
            [r,c] = size(app.tumor);
            filledImage = imfill(app.tumor, 'holes');

            for i=1:r
                for j=1:c
                    x1=i-rad;
                    x2=i+rad;
                    y1=j-rad;
                    y2=j+rad;
                    if x1<1
                        x1=1;
                    end
                    if x2>r
                        x2=r;
                    end
                    if y1<1
                        y1=1;
                    end
                    if y2>c
                        y2=c;
                    end
                    erodedImage(i,j) = min(min(filledImage(x1:x2,y1:y2)));
                end
            end
          
            % subtracting eroded image from original BW image

            tumorOutline=app.tumor;
            tumorOutline(erodedImage)=0;
          

            % Inserting the outline in input image in red color

            rgb = app.inp(:,:,[1 1 1]);
            red = rgb(:,:,1);
            red(tumorOutline)=255;
            green = rgb(:,:,2);
            green(tumorOutline)=0;
            blue = rgb(:,:,3);
            blue(tumorOutline)=0;

            tumorOutlineInserted(:,:,1) = red;
            tumorOutlineInserted(:,:,2) = green;
            tumorOutlineInserted(:,:,3) = blue;

            imshow(tumorOutlineInserted,'Parent',app.UIAxes_2);
            hold on;
            box = stats(tumor_label);
            wantedBox = box.BoundingBox;
                                  
            
            rectangle(app.UIAxes_2,'Position',wantedBox,'EdgeColor','y','LineWidth',2);
            hold off;
          
        end

        % Button pushed function: KmeansClusteringButton
        function KmeansClusteringButtonPushed(app, event)
            
            wd=256;                       
            Input=imresize(app.inp1,[256 256]);
          
            [r,c,p]   = size(Input);
            if p==3
                Input= Input(:,:,2);
                figure(2);
                imshow(Input);
                Input = imadjust(Input,[0.4 0.8],[]);
                
            end

            Input   =double(Input);
            Length = (r*c);
            Dataset = reshape(Input,[Length,1]);

            Clusters=4; %k CLUSTERS
            Cluster1=zeros(Length,1);
            Cluster2=zeros(Length,1);
            Cluster3=zeros(Length,1);
            Cluster4=zeros(Length,1);
            miniv = min(min(Input));
            maxiv = max(max(Input));
            range = maxiv - miniv;
            stepv = range/Clusters;
            incrval = stepv;
            for i = 1:Clusters
                K(i).centroid = incrval;
                incrval = incrval + stepv;
            end

            update1=0;
            update2=0;
            update3=0;
            update4=0;

            mean1=2;
            mean2=2;
            mean3=2;
            mean4=2;

            while  ((mean1 ~= update1) & (mean2 ~= update2) & (mean3 ~= update3) & (mean4 ~= update4))

                mean1=K(1).centroid;
                mean2=K(2).centroid;
                mean3=K(3).centroid;
                mean4=K(4).centroid;


                for i=1:Length
                    for j = 1:Clusters
                        temp= Dataset(i);
                        difference(j) = abs(temp-K(j).centroid);
                    end
                    [y,ind]=min(difference);
                    if ind==1
                        Cluster1(i)=temp;
                    end
                    if ind==2
                        Cluster2(i)=temp;
                    end
                    if ind==3
                        Cluster3(i)=temp;
                    end
                    if ind==4
                        Cluster4(i)   =temp;
                    end
                end

                %UPDATE CENTROIDS
                cout1=0;
                cout2=0;
                cout3=0;
                cout4=0;

                for i=1:Length
                    Load1=Cluster1(i);
                    Load2=Cluster2(i);
                    Load3=Cluster3(i);
                    Load4=Cluster4(i);

                    if Load1 ~= 0
                        cout1=cout1+1;
                    end
                    if Load2 ~= 0
                        cout2=cout2+1;
                    end
                    if Load3 ~= 0
                        cout3=cout3+1;
                    end
                    if Load4 ~= 0
                        cout4=cout4+1;
                    end
                end

                Mean_Cluster(1)=sum(Cluster1)/cout1;
                Mean_Cluster(2)=sum(Cluster2)/cout2;
                Mean_Cluster(3)=sum(Cluster3)/cout3;
                Mean_Cluster(4)=sum(Cluster4)/cout4;

                %reload
                for i = 1:Clusters
                    K(i).centroid = Mean_Cluster(i);
                end

                update1=K(1).centroid;
                update2=K(2).centroid;
                update3=K(3).centroid;
                update4=K(4).centroid;

            end

            AA1=reshape(Cluster1,[wd wd]);
            AA2=reshape(Cluster2,[wd wd]);
            AA3=reshape(Cluster3,[wd wd]);
            AA4=reshape(Cluster4,[wd wd]);

            imshow(AA4,'Parent',app.UIAxes_2);
        end

        % Button pushed function: RegionGrowingApproachButton
        function RegionGrowingApproachButtonPushed(app, event)
            reg_maxdist = 0.2;
            I = im2double(app.inp);
            figure
         
            imshow(I); 
            % let the user pick one point
            [y,x] = ginput(1);
            % round to integer to match required input by regiongrowing function
            x = round(x);
            y = round(y);
            % plot point on original image
            hold on;
            plot(y,x,'xg','MarkerSize',20,'LineWidth',2);
            hold off;
            % get region from seed point
            app.tumor = regiongrowing(I,x,y,reg_maxdist);
            
            nhood= ones(5,5);
            erodedImage= imerode(app.tumor,nhood);
            tumorOutline=app.tumor;
            tumorOutline(erodedImage)=0;

            % Inserting the outline in input image in red color

            rgb = app.inp(:,:,[1 1 1]);
            red = rgb(:,:,1);
            red(tumorOutline)=255;
            green = rgb(:,:,2);
            green(tumorOutline)=0;
            blue = rgb(:,:,3);
            blue(tumorOutline)=0;

            tumorOutlineInserted(:,:,1) = red;
            tumorOutlineInserted(:,:,2) = green;
            tumorOutlineInserted(:,:,3) = blue;

            imshow(tumorOutlineInserted,'Parent',app.UIAxes_2);
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Brain MRI image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Processed Brain MRI image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.BrainTumorDetectionBasedonSegmentationLabel = uilabel(app.UIFigure);
            app.BrainTumorDetectionBasedonSegmentationLabel.Text = 'Brain Tumor Detection Based on Segmentation';
            app.LoadaBrainMRIimageButton = uibutton(app.UIFigure, 'push');
            app.LoadaBrainMRIimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadaBrainMRIimageButtonPushed, true);
            app.LoadaBrainMRIimageButton.Text = 'Load a Brain MRI image';
            app.CoverttoGrayscaleButton = uibutton(app.UIFigure, 'push');
            app.CoverttoGrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @CoverttoGrayscaleButtonPushed, true);
            app.CoverttoGrayscaleButton.Text = 'Covert to Gray scale';
            app.ContrastInhancementButton = uibutton(app.UIFigure, 'push');
            app.ContrastInhancementButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastInhancementButtonPushed, true);
            app.ContrastInhancementButton.Text = 'Contrast Inhancement';
            app.HighPassFilteringButton = uibutton(app.UIFigure, 'push');
            app.HighPassFilteringButton.ButtonPushedFcn = createCallbackFcn(app, @HighPassFilteringButtonPushed, true);
            app.HighPassFilteringButton.Text = 'High Pass Filtering';
            app.EdgeDetectionTechniqueLabel = uilabel(app.UIFigure);
            app.EdgeDetectionTechniqueLabel.Text = 'Edge Detection Technique';
            app.SobelButton = uibutton(app.UIFigure, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.PrewittButton = uibutton(app.UIFigure, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.CannyButton = uibutton(app.UIFigure, 'push');
            app.CannyButton.ButtonPushedFcn = createCallbackFcn(app, @CannyButtonPushed, true);
            app.CannyButton.Text = 'Canny';
            app.FilterforRemovingNoiseLabel = uilabel(app.UIFigure);
            app.FilterforRemovingNoiseLabel.Text = 'Filter for Removing Noise ';
            app.MedianFilterButton = uibutton(app.UIFigure, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButtonPushed, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.SegmentationoftumorLabel = uilabel(app.UIFigure);
            app.SegmentationoftumorLabel.Text = 'Segmentation of tumor';
            app.ManualThresholdButton = uibutton(app.UIFigure, 'push');
            app.ManualThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ManualThresholdButtonPushed, true);
            app.ManualThresholdButton.Text = 'Manual Threshold';
            app.KmeansClusteringButton = uibutton(app.UIFigure, 'push');
            app.KmeansClusteringButton.ButtonPushedFcn = createCallbackFcn(app, @KmeansClusteringButtonPushed, true);
            app.KmeansClusteringButton.Text = 'K means Clustering';
            app.RegionGrowingApproachButton = uibutton(app.UIFigure, 'push');
            app.RegionGrowingApproachButton.ButtonPushedFcn = createCallbackFcn(app, @RegionGrowingApproachButtonPushed, true);
            app.RegionGrowingApproachButton.Text = 'Region Growing Approach';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.Text = 'Close';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            app.DevelopedByTasniaBinteMamun1818027Label = uilabel(app.UIFigure);
            app.DevelopedByTasniaBinteMamun1818027Label.Text = 'Developed By: Tasnia Binte Mamun (1818027';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_BME_404

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end